classdef Filters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SelectFiltertypeDropDownLabel  matlab.ui.control.Label
        SelectFiltertypeDropDown       matlab.ui.control.DropDown
        dBCutoffFrequencyEditFieldLabel  matlab.ui.control.Label
        dBCutoffFrequencyEditField     matlab.ui.control.NumericEditField
        BandWidthEditFieldLabel        matlab.ui.control.Label
        BandWidthEditField             matlab.ui.control.NumericEditField
        VisualizeButton                matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        UIAxesFilteredResponse         matlab.ui.control.UIAxes
        UIAxesSignalTimeDomain         matlab.ui.control.UIAxes
    end


    methods (Access = private)
    
        function plotFilter(app, b, a)
            app.plotSignalFFT;
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, 0:1/1000:1-1/1000, abs(freqz(b,a,1000)));
            hold(app.UIAxes, 'off');
        end
        
        function plotFilterResponse(app, b, a)
            Fs = 250;
            t = (0:1999)*1/Fs;
            x = sin(2*pi*25*t)+sin(2*pi*50*t)+1.5*sin(2*pi*50*t)+3*sin(2*pi*100*t);
            y = filter(b, a, x);
            Y = abs(fft(y))/2000;
            Y = fftshift(Y);
            plot(app.UIAxesFilteredResponse, linspace(0, 1, 1000), Y(1001:2000));
        end
        
        function plotLowPassFilter(app, cutoff)
            [b, a] = butter(3, cutoff, 'low');
            plotFilter(app, b, a);
            plotFilterResponse(app, b, a);
        end
        
        function plotHighPassFilter(app, cutoff)
            [b, a] = butter(3, cutoff, 'high');
            plotFilter(app, b, a);
            plotFilterResponse(app, b, a);
        end
        
        function plotBandPassFilter(app, cutoff, stopF)
            [b, a] = butter(3, [cutoff stopF], 'bandpass');
            plotFilter(app, b, a);
            plotFilterResponse(app, b, a);
        end
        
        function plotBandStopFilter(app, cutoff, stopF)
            [b, a] = butter(3, [cutoff stopF], 'stop');
            plotFilter(app, b, a);
            plotFilterResponse(app, b, a);
        end
        
        function plotSignalFFT(app)
            Fs = 250;
            t = (0:1999)*1/Fs;
            x = sin(2*pi*25*t)+sin(2*pi*50*t)+1.5*sin(2*pi*50*t)+3*sin(2*pi*100*t);
            Y = abs(fft(x))/2000;
            Y = fftshift(Y);
            plot(app.UIAxes, linspace(0, 1, 1000), Y(1001:2000));   
        end
        
        function plotSignal(app)
            Fs = 250;
            t = (0:1999)*1/Fs;
            x = sin(2*pi*25*t)+sin(2*pi*50*t)+1.5*sin(2*pi*50*t)+3*sin(2*pi*100*t);
            plot(app.UIAxesSignalTimeDomain, t(1:50), x(1:50));
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupAction(app)
            app.plotSignalFFT;
            app.plotSignal;
        end

        % Button pushed function: ResetButton
        function clickedResetAction(app, event)
            app.BandWidthEditField.Value = 0;
            app.dBCutoffFrequencyEditField.Value = 0;
            app.SelectFiltertypeDropDown.Value = 'None';
            app.BandWidthEditField.Editable = 'on';
            app.plotSignalFFT;
            app.plotSignal;
            app.UIAxesFilteredResponse.reset;
            app.UIAxesFilteredResponse.Title.String = 'Filtered Response';
            app.UIAxesFilteredResponse.XLabel.String = 'Normalized Frequency (x pi)';
            app.UIAxesFilteredResponse.YLabel.String = 'Magnitude';
        end

        % Button pushed function: VisualizeButton
        function clickedVisualizeAction(app, event)
            cutoff = app.dBCutoffFrequencyEditField.Value;
            bandWidth = app.BandWidthEditField.Value;
            filter_type = app.SelectFiltertypeDropDown.Value;
            %app.AnswerLabel.Text = [num2str(cutoff) ' '  num2str(bandWidth) ' ' filter_type];
            if strcmp(filter_type, 'None') 
                uialert(app.UIFigure, 'Please choose a filter', 'Warning!');
            else 
                if strcmp(filter_type, 'Low Pass Filter')
                    app.plotLowPassFilter(cutoff);
                else
                    if strcmp(filter_type, 'High Pass Filter')
                        app.plotHighPassFilter(cutoff);
                    else
                        if strcmp(filter_type, 'Band Pass Filter')
                            stopF = cutoff+bandWidth;
                            if stopF >= 1
                                app.plotHighPassFilter(cutoff);
                                uialert(app.UIFigure, 'Upper cutoff frequency exceeds 1. Therefore, plotting High Pass Filter', 'Alert!');
                            else
                                app.plotBandPassFilter(cutoff, stopF);
                            end
                        else
                            stopF = cutoff+bandWidth;
                            if stopF >= 1
                                app.plotLowPassFilter(cutoff);
                                uialert(app.UIFigure, 'Upper cutoff frequency exceeds 1. Therefore, plotting Low Pass Filter', 'Alert!');
                            else
                                app.plotBandStopFilter(cutoff, stopF);
                            end
                        end
                    end
                end
            end
            
        end

        % Value changed function: SelectFiltertypeDropDown
        function selectedFilter(app, event)
            value = app.SelectFiltertypeDropDown.Value;
            if strcmp(value, 'Low Pass Filter') || strcmp(value, 'High Pass Filter')
                app.BandWidthEditField.Editable = 'off';
                return;
            end
            if strcmp(app.BandWidthEditField.Editable, 'off')
                app.BandWidthEditField.Editable = 'on';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SelectFiltertypeDropDownLabel = uilabel(app.UIFigure);
            app.SelectFiltertypeDropDownLabel.Text = 'Select Filter type';
            app.SelectFiltertypeDropDown = uidropdown(app.UIFigure);
            app.SelectFiltertypeDropDown.Items = {'None', 'Low Pass Filter', 'High Pass Filter', 'Band Pass Filter', 'Band Stop Filter'};
            app.SelectFiltertypeDropDown.ValueChangedFcn = createCallbackFcn(app, @selectedFilter, true);
            app.SelectFiltertypeDropDown.Value = 'None';
            app.dBCutoffFrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.dBCutoffFrequencyEditFieldLabel.Text = '3-dB Cutoff Frequency';
            app.dBCutoffFrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.BandWidthEditFieldLabel = uilabel(app.UIFigure);
            app.BandWidthEditFieldLabel.Text = 'Band Width';
            app.BandWidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.VisualizeButton = uibutton(app.UIFigure, 'push');
            app.VisualizeButton.ButtonPushedFcn = createCallbackFcn(app, @clickedVisualizeAction, true);
            app.VisualizeButton.Text = 'Visualize';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @clickedResetAction, true);
            app.ResetButton.Text = 'Reset';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Signal FFT and Filter')
            xlabel(app.UIAxes, 'Normalized Frequency( x pi)')
            ylabel(app.UIAxes, 'Magnitude')
            app.UIAxesFilteredResponse = uiaxes(app.UIFigure);
            title(app.UIAxesFilteredResponse, 'Filtered Response')
            xlabel(app.UIAxesFilteredResponse, 'Normalized Frequency (x pi)')
            ylabel(app.UIAxesFilteredResponse, 'Magnitude')
            app.UIAxesSignalTimeDomain = uiaxes(app.UIFigure);
            title(app.UIAxesSignalTimeDomain, 'Signal in time-domain')
            xlabel(app.UIAxesSignalTimeDomain, 'Time (s)')
            ylabel(app.UIAxesSignalTimeDomain, 'Amplitude')
        end
    end

    methods (Access = public)

        % Construct app
        function app = Filters

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupAction)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end